classdef SpikeSortingRaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        ButtonGroup       matlab.ui.container.ButtonGroup
        HITtrialsButton   matlab.ui.control.RadioButton
        MISStrialsButton  matlab.ui.control.RadioButton
        NeuronIDLabel     matlab.ui.control.Label
        NeuronIDSlider    matlab.ui.control.Slider
        PrevButton        matlab.ui.control.Button
        NextButton        matlab.ui.control.Button
        LoadDataButton    matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NextButtonPushed(app, event)

        end

        % Value changed function: NeuronIDSlider
        function NeuronIDSliderValueChanged(app, event)
            value = app.NeuronIDSlider.Value;
            dat = Spikes.PSTH.hit.spkRates(value,:);
            bar(-size(dat,2):size(dat,2),smoothdata(dat),'FaceColor',[28/255 117/255 188/255],'EdgeColor','none')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select Calcium .mat File');
            tempx = load([path file]);
            app.x = tempx;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.HITtrialsButton = uiradiobutton(app.ButtonGroup);
            app.HITtrialsButton.Text = 'HIT trials';
            app.HITtrialsButton.Value = true;
            app.MISStrialsButton = uiradiobutton(app.ButtonGroup);
            app.MISStrialsButton.Text = 'MISS trials';
            app.NeuronIDLabel = uilabel(app.LeftPanel);
            app.NeuronIDLabel.Text = 'Neuron ID';
            app.NeuronIDSlider = uislider(app.LeftPanel);
            app.NeuronIDSlider.ValueChangedFcn = createCallbackFcn(app, @NeuronIDSliderValueChanged, true);
            app.PrevButton = uibutton(app.LeftPanel, 'push');
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.Text = 'Next';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Spike Raster Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Trials')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Firing Rate (Hz)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeSortingRaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end